classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnergyMarketApplicationUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        Label_2                        matlab.ui.control.Label
        EnergyExchangeAppLabel         matlab.ui.control.Label
        SoftwaresDesignandDevelopmentbyNikolaosKoutantosLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        EnergyExchangeGeneralsLabel    matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        GeneralTipsforUsersLabel       matlab.ui.control.Label
        ThepresentapplicationaremadeLabel  matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        GensLoadsTab                   matlab.ui.container.Tab
        UsersPanel                     matlab.ui.container.Panel
        UserEditField                  matlab.ui.control.NumericEditField
        UserLabel                      matlab.ui.control.Label
        RefreshMailsButton_2           matlab.ui.control.Button
        RoundidSpinner                 matlab.ui.control.Spinner
        RoundidSpinnerLabel            matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        SendMailTab                    matlab.ui.container.Tab
        SendButton                     matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        RecipientDropDown              matlab.ui.control.DropDown
        RecipientDropDownLabel         matlab.ui.control.Label
        IncomingTab                    matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        OutgoingTab                    matlab.ui.container.Tab
        UITable_2                      matlab.ui.control.Table
        LogoutButton                   matlab.ui.control.Button
        InputsPanel                    matlab.ui.container.Panel
        EntryUsersChoiseButtonGroup    matlab.ui.container.ButtonGroup
        ActualCostFunctionButton       matlab.ui.control.RadioButton
        BidCostFunctionButton          matlab.ui.control.RadioButton
        ButtonGroup                    matlab.ui.container.ButtonGroup
        PolynomialButton               matlab.ui.control.RadioButton
        LinearButton                   matlab.ui.control.RadioButton
        ResetButton                    matlab.ui.control.Button
        SubmitButton                   matlab.ui.control.Button
        PolynomialFormatPanel          matlab.ui.container.Panel
        C9Label                        matlab.ui.control.Label
        C8Label                        matlab.ui.control.Label
        C7Label                        matlab.ui.control.Label
        C6Label                        matlab.ui.control.Label
        C5Label                        matlab.ui.control.Label
        C4Label                        matlab.ui.control.Label
        C3Label                        matlab.ui.control.Label
        C2Label                        matlab.ui.control.Label
        C1Label                        matlab.ui.control.Label
        C0Label                        matlab.ui.control.Label
        EditField_20                   matlab.ui.control.NumericEditField
        EditField_19                   matlab.ui.control.NumericEditField
        EditField_18                   matlab.ui.control.NumericEditField
        EditField_17                   matlab.ui.control.NumericEditField
        EditField_16                   matlab.ui.control.NumericEditField
        EditField_15                   matlab.ui.control.NumericEditField
        EditField_14                   matlab.ui.control.NumericEditField
        EditField_13                   matlab.ui.control.NumericEditField
        EditField_12                   matlab.ui.control.NumericEditField
        EditField_11                   matlab.ui.control.NumericEditField
        LinearFormatPanel              matlab.ui.container.Panel
        hrLabel                        matlab.ui.control.Label
        MWLabel                        matlab.ui.control.Label
        EditField_10                   matlab.ui.control.NumericEditField
        EditField_9                    matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        UsersLoginPanel                matlab.ui.container.Panel
        GensLoadsLoginPanel            matlab.ui.container.Panel
        PasswordEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        UsernameEditField              matlab.ui.control.EditField
        UsernameEditFieldLabel         matlab.ui.control.Label
        SignInButton                   matlab.ui.control.Button
        AdminTab                       matlab.ui.container.Tab
        AdminsPreviewPanel             matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        SummaryTab                     matlab.ui.container.Tab
        q3Button                       matlab.ui.control.Button
        q2Button                       matlab.ui.control.Button
        q1Button                       matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        FixedLoadPercentageSpinner     matlab.ui.control.Spinner
        FixedLoadPercentageSpinnerLabel  matlab.ui.control.Label
        ClearMarketButton              matlab.ui.control.Button
        Panel_3                        matlab.ui.container.Panel
        BranchesPanel                  matlab.ui.container.Panel
        UploadChangesButton            matlab.ui.control.Button
        ChangeAllButton                matlab.ui.control.Button
        RegisterButton_2               matlab.ui.control.Button
        BranchIDEditField              matlab.ui.control.NumericEditField
        BranchIDEditFieldLabel         matlab.ui.control.Label
        ThermalLimitChangeEditField    matlab.ui.control.NumericEditField
        ThermalLimitChangeEditFieldLabel  matlab.ui.control.Label
        qButton                        matlab.ui.control.Button
        ONButton                       matlab.ui.control.Button
        UITable6                       matlab.ui.control.Table
        OFFButton                      matlab.ui.control.Button
        CostFunctionsExplorerPanel     matlab.ui.container.Panel
        UpdateButton_2                 matlab.ui.control.Button
        UserSpinner                    matlab.ui.control.Spinner
        UserSpinnerLabel               matlab.ui.control.Label
        RoundSpinner                   matlab.ui.control.Spinner
        RoundSpinnerLabel              matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        Panel_2                        matlab.ui.container.Panel
        OFFButton_2                    matlab.ui.control.Button
        ONButton_2                     matlab.ui.control.Button
        UITable7                       matlab.ui.control.Table
        RoundSpinner_2                 matlab.ui.control.Spinner
        RoundSpinner_2Label            matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        RoundSummary                   matlab.ui.container.Panel
        UsagePercentageEditField       matlab.ui.control.NumericEditField
        UsagePercentageEditFieldLabel  matlab.ui.control.Label
        ConvergedTimeEditField         matlab.ui.control.NumericEditField
        ConvergedTimeEditFieldLabel    matlab.ui.control.Label
        ObjectiveFunctionValueEditField  matlab.ui.control.NumericEditField
        ObjectiveFunctionValueEditFieldLabel  matlab.ui.control.Label
        ofEditField                    matlab.ui.control.NumericEditField
        ofEditFieldLabel               matlab.ui.control.Label
        DispLoadsEditField             matlab.ui.control.NumericEditField
        DispLoadsEditFieldLabel        matlab.ui.control.Label
        TotalQgEditField               matlab.ui.control.NumericEditField
        TotalQgEditFieldLabel          matlab.ui.control.Label
        TotalPgEditField               matlab.ui.control.NumericEditField
        TotalPgEditFieldLabel          matlab.ui.control.Label
        TotalQdEditField               matlab.ui.control.NumericEditField
        TotalQdEditFieldLabel          matlab.ui.control.Label
        TotalPdEditField               matlab.ui.control.NumericEditField
        TotalPdEditFieldLabel          matlab.ui.control.Label
        UITable3                       matlab.ui.control.Table
        UserMangementTab               matlab.ui.container.Tab
        RefreshLogsButton              matlab.ui.control.Button
        UITable11                      matlab.ui.control.Table
        RefreshMailsButton             matlab.ui.control.Button
        UITable10                      matlab.ui.control.Table
        ApplyChangesButton1            matlab.ui.control.Button
        NewUsernameEditField           matlab.ui.control.EditField
        NewUsernameEditFieldLabel      matlab.ui.control.Label
        UserIDSpinner                  matlab.ui.control.Spinner
        UserIDLabel                    matlab.ui.control.Label
        ApplyChangesButton             matlab.ui.control.Button
        NewPasswordEditField           matlab.ui.control.EditField
        NewPasswordEditFieldLabel      matlab.ui.control.Label
        UITable9                       matlab.ui.control.Table
        AdminsLoginPanel               matlab.ui.container.Panel
        AdminPanel                     matlab.ui.container.Panel
        PasswordEditField_2            matlab.ui.control.EditField
        PasswordEditField_2Label       matlab.ui.control.Label
        UsernameEditField_2            matlab.ui.control.EditField
        UsernameEditField_2Label       matlab.ui.control.Label
        SignInButton_2                 matlab.ui.control.Button
        GridInfoTab                    matlab.ui.container.Tab
        FileNameEditField_2            matlab.ui.control.EditField
        FileNameEditField_2Label       matlab.ui.control.Label
        ExportDataButton               matlab.ui.control.Button
        UITable4_2                     matlab.ui.control.Table
        RoundSpinner_3                 matlab.ui.control.Spinner
        RoundSpinner_3Label            matlab.ui.control.Label
        UpdateButton_4                 matlab.ui.control.Button
        UITable5                       matlab.ui.control.Table
        UIAxes3                        matlab.ui.control.UIAxes
        LoadManagementTab              matlab.ui.container.Tab
        UsersPanel_2                   matlab.ui.container.Panel
        UserEditField_2                matlab.ui.control.NumericEditField
        UserLabel_2                    matlab.ui.control.Label
        q4Button                       matlab.ui.control.Button
        UploadDataButton               matlab.ui.control.Button
        UITable8                       matlab.ui.control.Table
        PlotButton_2                   matlab.ui.control.Button
        RegisterButton                 matlab.ui.control.Button
        HourSpinner                    matlab.ui.control.Spinner
        HourSpinnerLabel               matlab.ui.control.Label
        LoadChangeEditField            matlab.ui.control.NumericEditField
        LoadChangeLabel                matlab.ui.control.Label
        LogoutButton_2                 matlab.ui.control.Button
        UIAxes5                        matlab.ui.control.UIAxes
        UsersLoginPanel_2              matlab.ui.container.Panel
        LoadsLoginPanel                matlab.ui.container.Panel
        PasswordEditField_3            matlab.ui.control.EditField
        PasswordEditField_4Label       matlab.ui.control.Label
        UsernameEditField_3            matlab.ui.control.EditField
        UsernameEditField_4Label       matlab.ui.control.Label
        SignInButton_3                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        se % Description
        inp % Description
        out % Description
        a % Description
        t % Description
        by % Description
        ay % Description
        tab % Description
  
        validate % Epistrofi gia swsti eisodo xristi validate=1;
        i%User pou syndethike
        k % Description
        Property13 % Description
        path
       
        info % Description
        t1 % Description
        t2 
        mails % gia ton admin
        row
        col % Description
        table % Description
        table2 % Descpiption
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            gridsetup(app.UIAxes3 , app.UIAxes4)
            
            javaaddpath(pwd + "/mysql-connector-java-8.0.16.jar")
            br_init(app.UITable6)
            pass_init(app.UITable9)
            boxd(1:10,1) = {false};
            br_initgen(app.UITable7,boxd)

        end

        % Button pushed function: SignInButton
        function SignInButtonPushed(app, event)
            %msgbox(app.UsernameEditField.Value)
          
            [app.validate.Value, app.i.Value] = users_sign(app.UsernameEditField.Value,app.PasswordEditField.Value)   
            if app.validate.Value == 1
                app.GensLoadsLoginPanel.Visible = 'off'
                app.UsersPanel.Visible='on'
                selog(app.i.Value, "Just signed in.")
                app.UserEditField.Value = app.i.Value;

              [app.inp.Value , app.out.Value] =  mailspreview(app.i.Value)
               set(app.UITable , 'data' , app.inp.Value)
               set(app.UITable_2, 'data', app.out.Value)

            end


        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
          if app.ActualCostFunctionButton.Value == 1
             if app.LinearButton.Value == 1
                   actualcost_entry(app.EditField.Value,app.EditField_6.Value,app.EditField_2.Value,app.EditField_7.Value,app.EditField_3.Value,app.EditField_8.Value,app.EditField_4.Value,app.EditField_9.Value,app.EditField_5.Value,app.EditField_10.Value,0,app.i.Value)
             end
                if app.PolynomialButton.Value == 1
                    actualcost_entry(app.EditField_20.Value,app.EditField_19.Value,app.EditField_18.Value,app.EditField_17.Value,app.EditField_16.Value,app.EditField_15.Value,app.EditField_14.Value,app.EditField_13.Value,app.EditField_12.Value,app.EditField_11.Value,1,app.i.Value)
              
                end
           
          end


            if app.BidCostFunctionButton.Value==1
            if app.LinearButton.Value == 1
                gencost_entry(app.EditField.Value,app.EditField_6.Value,app.EditField_2.Value,app.EditField_7.Value,app.EditField_3.Value,app.EditField_8.Value,app.EditField_4.Value,app.EditField_9.Value,app.EditField_5.Value,app.EditField_10.Value,0,app.i.Value)
            end
            if app.PolynomialButton.Value == 1
                 gencost_entry(app.EditField_20.Value,app.EditField_19.Value,app.EditField_18.Value,app.EditField_17.Value,app.EditField_16.Value,app.EditField_15.Value,app.EditField_14.Value,app.EditField_13.Value,app.EditField_12.Value,app.EditField_11.Value,1,app.i.Value)
              
            end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.EditField.Value = 0;app.EditField_2.Value = 0;app.EditField_3.Value = 0;app.EditField_4.Value = 0;app.EditField_5.Value = 0;
            app.EditField_6.Value = 0;app.EditField_7.Value = 0;app.EditField_8.Value = 0;app.EditField_9.Value = 0;app.EditField_10.Value = 0;
            app.EditField_11.Value = 0;app.EditField_12.Value = 0;app.EditField_13.Value = 0;app.EditField_14.Value = 0;app.EditField_15.Value = 0;
            app.EditField_16.Value = 0;app.EditField_17.Value = 0;app.EditField_18.Value = 0;app.EditField_19.Value = 0;app.EditField_20.Value = 0;
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            selog(app.UserEditField.Value, "Just logged out.")
            app.i.Value = 0;
            app.validate.Value = 0;
            app.UsersPanel.Visible = 'off'
            app.GensLoadsLoginPanel.Visible = 'on'
            cla(app.UIAxes , 'reset')
            app.UsernameEditField.Value = '';
            app.PasswordEditField.Value = '';

        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
           app.UITable.Data={0,1,2,'123','2626'}
           app.se.Value = recep(app.RecipientDropDown.Value)
           semail(app.i.Value, app.se.Value , app.TextArea.Value)
           app.TextArea.Value = '';
           app.RecipientDropDown.Value = 'gen01';
        end

        % Value changed function: RecipientDropDown
        function RecipientDropDownValueChanged(app, event)
            value = app.RecipientDropDown.Value;
            
        end

        % Button pushed function: SignInButton_2
        function SignInButton_2Pushed(app, event)
         [ app.a.Value ]= admin_sign(app.UsernameEditField_2.Value , app.PasswordEditField_2.Value)
        
           if( app.a.Value == 1)
           app.AdminsLoginPanel.Visible = 'off';
           app.AdminsPreviewPanel.Visible = 'on';
           app.UITable6.Data{1,5} = 0;

           app.mails.Value = mailadmin();
           set(app.UITable10,'data',app.mails.Value)
           logs = logadmin();
           set(app.UITable11,'data',logs)
           end
           
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes , 'reset')
            plotarei(app.UIAxes , app.i.Value , app.RoundidSpinner.Value)
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            [app.TotalPdEditField.Value app.TotalQdEditField.Value app.TotalPgEditField.Value app.TotalQgEditField.Value app.DispLoadsEditField.Value app.ofEditField.Value app.ObjectiveFunctionValueEditField.Value app.ConvergedTimeEditField.Value app.UsagePercentageEditField.Value] = roundsum(app.RoundSpinner_2.Value);
                app.tab.Value = roundsumtab(app.RoundSpinner_2.Value);
                set(app.UITable3, 'data' , app.tab.Value)
            uit = app.UITable3;
            data = uit.Data;
            data = table2array(data);

%%to reset colour column%%
            s3 = uistyle;
            s3.FontColor = 'black';
            s3.FontWeight = 'normal';
            addStyle(uit,s3,'column',6)            
%%for positive percentages%%
            [row,col] = find(data > 2);
            s1 = uistyle;
            s1.FontColor = 'red';
            s1.FontWeight = 'bold';
            addStyle(uit,s1,'cell',[row,col])
%%for negative percentages%%
            [row,col] = find(data < -2);
            s2 = uistyle;
            s2.FontColor = '#1CE21C';
            s2.FontWeight = 'bold';
            addStyle(uit,s2,'cell',[row,col])
%%to reset other columns%%
            s3 = uistyle;
            s3.FontColor = 'black';
            s3.FontWeight = 'normal';
            addStyle(uit,s3,'column',[1 2 3 4 5 7])
        end

        % Callback function
        function UserDropDownValueChanged(app, event)
    app.i.Value = app.UserDropDown.Value
      msgbox(app.i.Value)
        
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
         cla(app.UIAxes2 , 'reset')
                
             plotarei(app.UIAxes2 , app.UserSpinner.Value , app.RoundSpinner.Value)
           
        end

        % Callback function
        function RoundEditFieldValueChanged(app, event)
           
            
        end

        % Value changed function: RoundSpinner
        function RoundSpinnerValueChanged(app, event)


        end

        % Value changing function: RoundSpinner
        function RoundSpinnerValueChanging(app, event)
           
        end

        % Callback function
        function ListBox_4ValueChanged(app, event)
            app.Property13.Value = app.ListBox_4.Value;
            msgbox(app.Property13.Value)
        end

        % Button pushed function: ClearMarketButton
        function ClearMarketButtonPushed(app, event)
            pdchange(app.FixedLoadPercentageSpinner.Value)
            boxdata = app.UITable7.Data(:,3);
            genauto(boxdata)
            Admin_test
            
        end

        % Callback function
        function InitializationButtonPushed(app, event)
            trun
        end

        % Callback function
        function INITButtonPushed(app, event)
            trun(app.FileNameEditField.Value)
        end

        % Value changed function: FixedLoadPercentageSpinner
        function FixedLoadPercentageSpinnerValueChanged(app, event)
            
            
        end

        % Callback function
        function Pd_ChangeButtonPushed(app, event)
            pdchange(app.FixedLoadPercentageSpinner.Value)
        end

        % Callback function
        function UpdateButton_3Pushed(app, event)
        app.info.Value = infround()
        set(app.UITable4, 'data', app.info.Value)
        end

        % Button pushed function: UpdateButton_4
        function UpdateButton_4Pushed(app, event)
            [app.t1.Value app.t2.Value] = live(app.RoundSpinner_3.Value)
      
            set(app.UITable5,'data',app.t2.Value)
            set(app.UITable4_2, 'data', app.t1.Value)

            uit1 = app.UITable5;
            data1 = uit1.Data;
            data1 = table2array(data1);
%%for positive percentages%%
            [row,col] = find(data1 > 1);
            s1 = uistyle;
            s1.FontColor = 'red';
            s1.FontWeight = 'bold';
            addStyle(uit1,s1,'cell',[row,col])
%%for negative percentages%%
            [row,col] = find(data1 < -1);
            s2 = uistyle;
            s2.FontColor = '#1CE21C';
            s2.FontWeight = 'bold';
            addStyle(uit1,s2,'cell',[row,col])
%%to reset other columns%%
            s3 = uistyle;
            s3.FontColor = 'black';
            s3.FontWeight = 'normal';
            addStyle(uit1,s3,'column',[1 2 3 5])
            
        end

        % Cell selection callback: UITable6
        function UITable6CellSelection(app, event)
            app.row = event.Indices(1);
            app.col = event.Indices(2);
           
        end

        % Button pushed function: OFFButton
        function OFFButtonPushed(app, event)
          sh(app.row , 0);
          br_init(app.UITable6);
        end

        % Button pushed function: ONButton
        function ONButtonPushed(app, event)
            sh(app.row ,1)
            br_init(app.UITable6)
        end

        % Button pushed function: SignInButton_3
        function SignInButton_3Pushed(app, event)
            %msgbox(app.UsernameEditField.Value)
          
            [app.validate.Value, app.i.Value] = loads_sign(app.UsernameEditField_3.Value,app.PasswordEditField_3.Value)   
            if app.validate.Value == 1
                app.LoadsLoginPanel.Visible = 'off'
                app.UsersPanel_2.Visible='on'
                selog(app.i.Value, "Just signed in to Load Management.")
                app.UserEditField_2.Value = app.i.Value;
                
              [app.inp.Value] =  loadusers(app.i.Value)
               set(app.UITable8 , 'data' , app.inp.Value)
               app.UITable8.Data = {};
          
                 
            end
            
            
        end

        % Button pushed function: LogoutButton_2
        function LogoutButton_2Pushed(app, event)
            selog(app.UserEditField_2.Value , "Just logged out from Load Management.")
            app.i.Value = 0;
            app.validate.Value = 0;
            app.UsersPanel_2.Visible = 'off'
            app.LoadsLoginPanel.Visible = 'on'
            app.UITable8.Data = {};
            app.table = {};
            app.HourSpinner.Value = 1;
            app.LoadChangeEditField.Value = 0;
            cla(app.UIAxes5 , 'reset')

        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            load = app.LoadChangeEditField.Value;
            hour = app.HourSpinner.Value;
            nr = {hour load};
            app.UITable8.Data = [app.table;nr];
            app.table = app.UITable8.Data;
            hour = hour +1;
            app.HourSpinner.Value = hour;
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
             cla(app.UIAxes5 , 'reset')
                
             histoplot(app.UIAxes5 , app.i.Value)
        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
             loads(app.i.Value,app.UITable8.Data);
             
        end

        % Button pushed function: OFFButton_2
        function OFFButton_2Pushed(app, event)
            shgen(app.row , 0);
            boxdata = app.UITable7.Data(:,3);
            br_initgen(app.UITable7,boxdata)
        end

        % Button pushed function: ONButton_2
        function ONButton_2Pushed(app, event)
             shgen(app.row ,1)
             boxdata = app.UITable7.Data(:,3);
             br_initgen(app.UITable7,boxdata)
        end

        % Cell selection callback: UITable7
        function UITable7CellSelection(app, event)
            app.row = event.Indices(1);
            app.col = event.Indices(2);
           
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            pdchange(app.FixedLoadPercentageSpinner.Value)
            exportcase(app.FileNameEditField.Value)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            trun(app.FileNameEditField.Value)
            boxd(1:10,1) = {false};
            br_initgen(app.UITable7,boxd)
            br_init(app.UITable6)
        end

        % Button pushed function: qButton
        function qqButtonPushed(app, event)
            helpdlg(["Above is the Generator Status tab and below is the Branches tab.",
             "Here the Admin can shut down Generators and/or remove branches from the grid.",
             "They can also set a generator's status to auto, so that it will submit a standard bid offer every round.",
             "In addition, they can increase or decrease the thermal limit of specific branches or all of them together, by a percentage between -50% , 50%."])
        end

        % Button pushed function: q1Button
        function q1ButtonPushed2(app, event)
            helpdlg(["Here the admin can set the total actual fixed load's percentage."])
        end

        % Button pushed function: q2Button
        function q2ButtonPushed2(app, event)
            helpdlg(["Here the Admin can export a modified scenario of his choice, so that he can import it later as a base case.",
             "The scenario will be saved as: 'text in the File Name text field'.m in the current path.",
             "In order to import or export a scenario you first need to have written its name in the File Name text field when you push the button.",
             "BE CAREFUL: if there is already a file with the same name in this path it will be overwritten!"])
        end

        % Button pushed function: q3Button
        function q3ButtonPushed2(app, event)
            helpdlg("By pressing the 'ClearMarket' button the app will run the network's OPF and proceed to the next round(hour) of biddings.")
        end

        % Button pushed function: q4Button
        function q4ButtonPushed(app, event)
            helpdlg(["Here a dispachable load user can change their load percentage for each hour by a +-20% margin.",
             "For each hour put in the desired value and then hit 'Register'. Then put in the value for next hours if you wish.",
             "After you have registered all the changes you want press 'Load Data' before you log out."])
        end

        % Button pushed function: RegisterButton_2
        function RegisterButton2Pushed(app, event)

            rn = app.BranchIDEditField.Value;
            app.UITable6.Data{rn,5} = app.ThermalLimitChangeEditField.Value;

        end

        % Button pushed function: ChangeAllButton
        function ChangeAllButtonPushed(app, event)
            app.UITable6.Data{:,5} = app.ThermalLimitChangeEditField.Value;
        end

        % Button pushed function: UploadChangesButton
        function UploadChangesButtonPushed(app, event)
            limits(app.UITable6.Data);
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            passchange(app.UserIDSpinner.Value,app.NewPasswordEditField.Value)
            pass_init(app.UITable9)
        end

        % Button pushed function: ApplyChangesButton1
        function ApplyChangesButton1Pushed(app, event)
           unamechange(app.UserIDSpinner.Value,app.NewUsernameEditField.Value)
           pass_init(app.UITable9)
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            exportdata(app.FileNameEditField_2.Value,app.FixedLoadPercentageSpinner.Value)
        end

        % Button pushed function: RefreshMailsButton
        function RefreshMailsButtonPushed(app, event)
            app.mails.Value = mailadmin();
            set(app.UITable10,'data',app.mails.Value)
        end

        % Button pushed function: RefreshMailsButton_2
        function RefreshMailsButton2Pushed(app, event)
            [app.inp.Value , app.out.Value] =  mailspreview(app.i.Value)
               set(app.UITable , 'data' , app.inp.Value)
               set(app.UITable_2, 'data', app.out.Value)
        end

        % Button pushed function: RefreshLogsButton
        function RefreshLogsButtonPushed(app, event)
           logs = logadmin();
           set(app.UITable11,'data',logs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnergyMarketApplicationUIFigure = uifigure('Visible', 'off');
            app.EnergyMarketApplicationUIFigure.Name = 'Energy Market Application';
            app.TabGroup = uitabgroup(app.EnergyMarketApplicationUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.UIAxes4 = uiaxes(app.HomeTab);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.ThepresentapplicationaremadeLabel = uilabel(app.HomeTab);
            app.ThepresentapplicationaremadeLabel.Text = {'The present application are made for educational reasons on Power Systems.'; ''; 'It is adressed to simulate an online energy market where all the genarators and dispatchable loads, submit their Cost Functions in asychronous mode .This is possible, after logging in,  through Gens-Load Tab.'; 'These functions, as also the grid''s informations, such as buses, branches , generator data and other useful tables, are stored in an SQL database.This database has been setup for cloud usage and allows us from distance data '; 'manipulation, as also we are able to create a historical dataset for further analysis.'; ''; ''; 'This application also provides to the System''s Administrator, a lot of privileges on system''s editable properties , through Admin''s Tab.The first privilege is the Market clearing , where through Matlab''s Open Source package,'; 'which is called Matpower, we are able to clear the market as an Optimal Power Flow Problem.This problem constitutes from Matpower''s case39 scenario as base and then we''ve added five dispatchable loads for this market.'; 'Admin can also change standard loads,  poweroff branches and also initialize market''s scenario, based on his willing. Through this tab historical data are represented , with target on system''s better supervison.'; ''; 'The latest Tab is also for public usage and provide informations, about previous market rounds , nodal prices and Actual Generators Output  '};
            app.GeneralTipsforUsersLabel = uilabel(app.HomeTab);
            app.GeneralTipsforUsersLabel.Text = 'General Tips for Users.';
            app.sLabel = uilabel(app.HomeTab);
            app.sLabel.Text = {'As user you can modify your Cost Function and also another one function that represents the real cost of this user. This property had been added with target, each user to know his net profit.'; 'For Cost Function format users had two choices.The first one is in Piecewise Linear format , where you add your data in blocks of quantity and price.Totally 5 blocks.'; 'The second choise is Polynomial Cost Functions , where the user insert his coefficients.'};
            app.EnergyExchangeGeneralsLabel = uilabel(app.HomeTab);
            app.EnergyExchangeGeneralsLabel.Text = 'Energy Exchange Generals';
            app.Label = uilabel(app.HomeTab);
            app.Label.Text = {'Both Generators and Elastic Loads submit their functions with positive floats or integers.'; 'For Dispatcable loads in linear entry the first block represents the less significant quantity of energy.'};
            app.SoftwaresDesignandDevelopmentbyNikolaosKoutantosLabel = uilabel(app.HomeTab);
            app.SoftwaresDesignandDevelopmentbyNikolaosKoutantosLabel.Text = 'Software''s Design and Development by Nikolaos Koutantos';
            app.EnergyExchangeAppLabel = uilabel(app.HomeTab);
            app.EnergyExchangeAppLabel.Text = 'Energy Exchange App';
            app.Label_2 = uilabel(app.HomeTab);
            app.Label_2.Text = 'In every tab if you need information about the app''s functions, find and press the Help (?) Buttons.';
            app.GensLoadsTab = uitab(app.TabGroup);
            app.GensLoadsTab.Title = 'Gens-Loads';
            app.UsersLoginPanel = uipanel(app.GensLoadsTab);
            app.UsersLoginPanel.Title = 'UsersLogin';
            app.GensLoadsLoginPanel = uipanel(app.UsersLoginPanel);
            app.GensLoadsLoginPanel.Title = 'Gens-Loads Login Panel';
            app.SignInButton = uibutton(app.GensLoadsLoginPanel, 'push');
            app.SignInButton.ButtonPushedFcn = createCallbackFcn(app, @SignInButtonPushed, true);
            app.SignInButton.Text = {'Sign-In'; ''};
            app.UsernameEditFieldLabel = uilabel(app.GensLoadsLoginPanel);
            app.UsernameEditFieldLabel.Text = {'Username'; ''};
            app.UsernameEditField = uieditfield(app.GensLoadsLoginPanel, 'text');
            app.PasswordEditFieldLabel = uilabel(app.GensLoadsLoginPanel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.GensLoadsLoginPanel, 'text');
            app.UsersPanel = uipanel(app.GensLoadsTab);
            app.UsersPanel.Title = 'UsersPanel';
            app.UsersPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.UsersPanel);
            title(app.UIAxes, 'Ploting Area')
            xlabel(app.UIAxes, 'P')
            ylabel(app.UIAxes, '$/MWhr')
            app.InputsPanel = uipanel(app.UsersPanel);
            app.InputsPanel.Title = 'Inputs';
            app.LinearFormatPanel = uipanel(app.InputsPanel);
            app.LinearFormatPanel.Title = 'Linear Format';
            app.EditField = uieditfield(app.LinearFormatPanel, 'numeric');
            app.EditField_2 = uieditfield(app.LinearFormatPanel, 'numeric');
            app.EditField_3 = uieditfield(app.LinearFormatPanel, 'numeric');
            app.EditField_4 = uieditfield(app.LinearFormatPanel, 'numeric');
            app.EditField_5 = uieditfield(app.LinearFormatPanel, 'numeric');
            app.EditField_6 = uieditfield(app.LinearFormatPanel, 'numeric');
            app.EditField_7 = uieditfield(app.LinearFormatPanel, 'numeric');
            app.EditField_8 = uieditfield(app.LinearFormatPanel, 'numeric');
            app.EditField_9 = uieditfield(app.LinearFormatPanel, 'numeric');
            app.EditField_10 = uieditfield(app.LinearFormatPanel, 'numeric');
            app.MWLabel = uilabel(app.LinearFormatPanel);
            app.MWLabel.Text = {'MW'; ''};
            app.hrLabel = uilabel(app.LinearFormatPanel);
            app.hrLabel.Text = '$/hr';
            app.PolynomialFormatPanel = uipanel(app.InputsPanel);
            app.PolynomialFormatPanel.Title = 'Polynomial Format';
            app.EditField_11 = uieditfield(app.PolynomialFormatPanel, 'numeric');
            app.EditField_12 = uieditfield(app.PolynomialFormatPanel, 'numeric');
            app.EditField_13 = uieditfield(app.PolynomialFormatPanel, 'numeric');
            app.EditField_14 = uieditfield(app.PolynomialFormatPanel, 'numeric');
            app.EditField_15 = uieditfield(app.PolynomialFormatPanel, 'numeric');
            app.EditField_16 = uieditfield(app.PolynomialFormatPanel, 'numeric');
            app.EditField_17 = uieditfield(app.PolynomialFormatPanel, 'numeric');
            app.EditField_18 = uieditfield(app.PolynomialFormatPanel, 'numeric');
            app.EditField_19 = uieditfield(app.PolynomialFormatPanel, 'numeric');
            app.EditField_20 = uieditfield(app.PolynomialFormatPanel, 'numeric');
            app.C0Label = uilabel(app.PolynomialFormatPanel);
            app.C0Label.Text = 'C0';
            app.C1Label = uilabel(app.PolynomialFormatPanel);
            app.C1Label.Text = {'C1'; ''};
            app.C2Label = uilabel(app.PolynomialFormatPanel);
            app.C2Label.Text = 'C2';
            app.C3Label = uilabel(app.PolynomialFormatPanel);
            app.C3Label.Text = 'C3';
            app.C4Label = uilabel(app.PolynomialFormatPanel);
            app.C4Label.Text = 'C4';
            app.C5Label = uilabel(app.PolynomialFormatPanel);
            app.C5Label.Text = 'C5';
            app.C6Label = uilabel(app.PolynomialFormatPanel);
            app.C6Label.Text = 'C6';
            app.C7Label = uilabel(app.PolynomialFormatPanel);
            app.C7Label.Text = 'C7';
            app.C8Label = uilabel(app.PolynomialFormatPanel);
            app.C8Label.Text = 'C8';
            app.C9Label = uilabel(app.PolynomialFormatPanel);
            app.C9Label.Text = 'C9';
            app.SubmitButton = uibutton(app.InputsPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ResetButton = uibutton(app.InputsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ButtonGroup = uibuttongroup(app.InputsPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.LinearButton = uiradiobutton(app.ButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.PolynomialButton = uiradiobutton(app.ButtonGroup);
            app.PolynomialButton.Text = 'Polynomial';
            app.EntryUsersChoiseButtonGroup = uibuttongroup(app.InputsPanel);
            app.EntryUsersChoiseButtonGroup.Title = 'Entry User''s Choise';
            app.BidCostFunctionButton = uiradiobutton(app.EntryUsersChoiseButtonGroup);
            app.BidCostFunctionButton.Text = 'BidCostFunction';
            app.BidCostFunctionButton.Value = true;
            app.ActualCostFunctionButton = uiradiobutton(app.EntryUsersChoiseButtonGroup);
            app.ActualCostFunctionButton.Text = 'ActualCostFunction';
            app.LogoutButton = uibutton(app.UsersPanel, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = {'Logout'; ''};
            app.TabGroup2 = uitabgroup(app.UsersPanel);
            app.SendMailTab = uitab(app.TabGroup2);
            app.SendMailTab.Title = 'SendMail';
            app.RecipientDropDownLabel = uilabel(app.SendMailTab);
            app.RecipientDropDownLabel.Text = 'Recipient';
            app.RecipientDropDown = uidropdown(app.SendMailTab);
            app.RecipientDropDown.Items = {'gen01', 'gen02', 'gen03', 'gen04', 'gen05', 'gen06', 'gen07', 'gen08', 'gen09', 'gen10', 'load01', 'load02', 'load03', 'load04', 'load05', ''};
            app.RecipientDropDown.ValueChangedFcn = createCallbackFcn(app, @RecipientDropDownValueChanged, true);
            app.RecipientDropDown.Value = 'gen01';
            app.TextArea = uitextarea(app.SendMailTab);
            app.TextArea.Placeholder = 'Enter text up to 200 characters...';
            app.SendButton = uibutton(app.SendMailTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.IncomingTab = uitab(app.TabGroup2);
            app.IncomingTab.Title = 'Incoming';
            app.UITable = uitable(app.IncomingTab);
            app.UITable.ColumnName = {'From'; 'Text'; 'Date'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true];
            app.OutgoingTab = uitab(app.TabGroup2);
            app.OutgoingTab.Title = 'Outgoing';
            app.UITable_2 = uitable(app.OutgoingTab);
            app.UITable_2.ColumnName = {'To'; 'Text'; 'Date'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnSortable = [true false true];
            app.PlotButton = uibutton(app.UsersPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RoundidSpinnerLabel = uilabel(app.UsersPanel);
            app.RoundidSpinnerLabel.Text = 'Roundid';
            app.RoundidSpinner = uispinner(app.UsersPanel);
            app.RoundidSpinner.Limits = [-1 Inf];
            app.RefreshMailsButton_2 = uibutton(app.UsersPanel, 'push');
            app.RefreshMailsButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshMailsButton2Pushed, true);
            app.RefreshMailsButton_2.Text = 'Refresh Mails';
            app.UserLabel = uilabel(app.UsersPanel);
            app.UserLabel.Text = 'User :';
            app.UserEditField = uieditfield(app.UsersPanel, 'numeric');
            app.UserEditField.Editable = 'off';
            app.AdminTab = uitab(app.TabGroup);
            app.AdminTab.Title = 'Admin';
            app.AdminsLoginPanel = uipanel(app.AdminTab);
            app.AdminsLoginPanel.Title = 'AdminsLogin';
            app.AdminPanel = uipanel(app.AdminsLoginPanel);
            app.AdminPanel.Title = 'Admin';
            app.SignInButton_2 = uibutton(app.AdminPanel, 'push');
            app.SignInButton_2.ButtonPushedFcn = createCallbackFcn(app, @SignInButton_2Pushed, true);
            app.SignInButton_2.Text = {'Sign-In'; ''};
            app.UsernameEditField_2Label = uilabel(app.AdminPanel);
            app.UsernameEditField_2Label.Text = {'Username'; ''};
            app.UsernameEditField_2 = uieditfield(app.AdminPanel, 'text');
            app.PasswordEditField_2Label = uilabel(app.AdminPanel);
            app.PasswordEditField_2Label.Text = 'Password';
            app.PasswordEditField_2 = uieditfield(app.AdminPanel, 'text');
            app.AdminsPreviewPanel = uipanel(app.AdminTab);
            app.AdminsPreviewPanel.Title = 'Admins Preview';
            app.AdminsPreviewPanel.Visible = 'off';
            app.TabGroup3 = uitabgroup(app.AdminsPreviewPanel);
            app.SummaryTab = uitab(app.TabGroup3);
            app.SummaryTab.Title = 'Summary';
            app.Panel_2 = uipanel(app.SummaryTab);
            app.UITable3 = uitable(app.Panel_2);
            app.UITable3.ColumnName = {'User'; 'Bus'; 'Pg'; 'Qg'; 'Price'; 'Price Diff(%)'; 'Profit'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnSortable = true;
            app.RoundSummary = uipanel(app.Panel_2);
            app.RoundSummary.Title = 'Round Summary';
            app.TotalPdEditFieldLabel = uilabel(app.RoundSummary);
            app.TotalPdEditFieldLabel.Text = 'Total Pd';
            app.TotalPdEditField = uieditfield(app.RoundSummary, 'numeric');
            app.TotalPdEditField.ValueDisplayFormat = '%5.2f';
            app.TotalQdEditFieldLabel = uilabel(app.RoundSummary);
            app.TotalQdEditFieldLabel.Text = 'Total Qd';
            app.TotalQdEditField = uieditfield(app.RoundSummary, 'numeric');
            app.TotalQdEditField.ValueDisplayFormat = '%5.2f';
            app.TotalPgEditFieldLabel = uilabel(app.RoundSummary);
            app.TotalPgEditFieldLabel.Text = 'Total Pg';
            app.TotalPgEditField = uieditfield(app.RoundSummary, 'numeric');
            app.TotalPgEditField.ValueDisplayFormat = '%5.2f';
            app.TotalQgEditFieldLabel = uilabel(app.RoundSummary);
            app.TotalQgEditFieldLabel.Text = 'Total Qg';
            app.TotalQgEditField = uieditfield(app.RoundSummary, 'numeric');
            app.TotalQgEditField.ValueDisplayFormat = '%5.2f';
            app.DispLoadsEditFieldLabel = uilabel(app.RoundSummary);
            app.DispLoadsEditFieldLabel.Text = 'DispLoads';
            app.DispLoadsEditField = uieditfield(app.RoundSummary, 'numeric');
            app.ofEditFieldLabel = uilabel(app.RoundSummary);
            app.ofEditFieldLabel.Text = 'of';
            app.ofEditField = uieditfield(app.RoundSummary, 'numeric');
            app.ObjectiveFunctionValueEditFieldLabel = uilabel(app.RoundSummary);
            app.ObjectiveFunctionValueEditFieldLabel.Text = 'Objective Function Value';
            app.ObjectiveFunctionValueEditField = uieditfield(app.RoundSummary, 'numeric');
            app.ConvergedTimeEditFieldLabel = uilabel(app.RoundSummary);
            app.ConvergedTimeEditFieldLabel.Text = 'Converged Time';
            app.ConvergedTimeEditField = uieditfield(app.RoundSummary, 'numeric');
            app.UsagePercentageEditFieldLabel = uilabel(app.RoundSummary);
            app.UsagePercentageEditFieldLabel.Text = 'Usage Percentage';
            app.UsagePercentageEditField = uieditfield(app.RoundSummary, 'numeric');
            app.UpdateButton = uibutton(app.Panel_2, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.RoundSpinner_2Label = uilabel(app.Panel_2);
            app.RoundSpinner_2Label.Text = 'Round';
            app.RoundSpinner_2 = uispinner(app.Panel_2);
            app.RoundSpinner_2.Limits = [0 Inf];
            app.UITable7 = uitable(app.Panel_2);
            app.UITable7.ColumnName = {'Generator'; 'Status'; 'Auto'};
            app.UITable7.RowName = {};
            app.UITable7.ColumnEditable = [false false true];
            app.UITable7.CellSelectionCallback = createCallbackFcn(app, @UITable7CellSelection, true);
            app.ONButton_2 = uibutton(app.Panel_2, 'push');
            app.ONButton_2.ButtonPushedFcn = createCallbackFcn(app, @ONButton_2Pushed, true);
            app.ONButton_2.Text = 'ON';
            app.OFFButton_2 = uibutton(app.Panel_2, 'push');
            app.OFFButton_2.ButtonPushedFcn = createCallbackFcn(app, @OFFButton_2Pushed, true);
            app.OFFButton_2.Text = 'OFF';
            app.Panel_3 = uipanel(app.SummaryTab);
            app.CostFunctionsExplorerPanel = uipanel(app.Panel_3);
            app.CostFunctionsExplorerPanel.Title = 'Cost Functions Explorer';
            app.UIAxes2 = uiaxes(app.CostFunctionsExplorerPanel);
            title(app.UIAxes2, 'Cost Function')
            xlabel(app.UIAxes2, '$/MW')
            ylabel(app.UIAxes2, {'MW'; ''})
            app.RoundSpinnerLabel = uilabel(app.CostFunctionsExplorerPanel);
            app.RoundSpinnerLabel.Text = 'Round';
            app.RoundSpinner = uispinner(app.CostFunctionsExplorerPanel);
            app.RoundSpinner.ValueChangingFcn = createCallbackFcn(app, @RoundSpinnerValueChanging, true);
            app.RoundSpinner.Limits = [0 Inf];
            app.RoundSpinner.ValueChangedFcn = createCallbackFcn(app, @RoundSpinnerValueChanged, true);
            app.UserSpinnerLabel = uilabel(app.CostFunctionsExplorerPanel);
            app.UserSpinnerLabel.Text = 'User';
            app.UserSpinner = uispinner(app.CostFunctionsExplorerPanel);
            app.UserSpinner.Limits = [0 15];
            app.UpdateButton_2 = uibutton(app.CostFunctionsExplorerPanel, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update';
            app.BranchesPanel = uipanel(app.Panel_3);
            app.BranchesPanel.Title = 'Branches';
            app.OFFButton = uibutton(app.BranchesPanel, 'push');
            app.OFFButton.ButtonPushedFcn = createCallbackFcn(app, @OFFButtonPushed, true);
            app.OFFButton.Text = 'OFF';
            app.UITable6 = uitable(app.BranchesPanel);
            app.UITable6.ColumnName = {'Branch_id'; 'From'; 'To'; 'Status'; 'Thermal Limit (%)'};
            app.UITable6.RowName = {};
            app.UITable6.CellSelectionCallback = createCallbackFcn(app, @UITable6CellSelection, true);
            app.ONButton = uibutton(app.BranchesPanel, 'push');
            app.ONButton.ButtonPushedFcn = createCallbackFcn(app, @ONButtonPushed, true);
            app.ONButton.Text = 'ON';
            app.qButton = uibutton(app.BranchesPanel, 'push');
            app.qButton.ButtonPushedFcn = createCallbackFcn(app, @qqButtonPushed, true);
            app.qButton.Text = '?';
            app.ThermalLimitChangeEditFieldLabel = uilabel(app.BranchesPanel);
            app.ThermalLimitChangeEditFieldLabel.Text = 'Thermal Limit Change';
            app.ThermalLimitChangeEditField = uieditfield(app.BranchesPanel, 'numeric');
            app.ThermalLimitChangeEditField.Limits = [-50 50];
            app.BranchIDEditFieldLabel = uilabel(app.BranchesPanel);
            app.BranchIDEditFieldLabel.Text = 'Branch ID';
            app.BranchIDEditField = uieditfield(app.BranchesPanel, 'numeric');
            app.BranchIDEditField.Limits = [1 46];
            app.BranchIDEditField.Value = 1;
            app.RegisterButton_2 = uibutton(app.BranchesPanel, 'push');
            app.RegisterButton_2.ButtonPushedFcn = createCallbackFcn(app, @RegisterButton2Pushed, true);
            app.RegisterButton_2.Text = 'Register';
            app.ChangeAllButton = uibutton(app.BranchesPanel, 'push');
            app.ChangeAllButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeAllButtonPushed, true);
            app.ChangeAllButton.Text = 'Change All';
            app.UploadChangesButton = uibutton(app.BranchesPanel, 'push');
            app.UploadChangesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadChangesButtonPushed, true);
            app.UploadChangesButton.Text = 'Upload Changes';
            app.ClearMarketButton = uibutton(app.SummaryTab, 'push');
            app.ClearMarketButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMarketButtonPushed, true);
            app.ClearMarketButton.Text = 'ClearMarket';
            app.FixedLoadPercentageSpinnerLabel = uilabel(app.SummaryTab);
            app.FixedLoadPercentageSpinnerLabel.Text = 'Fixed Load Percentage';
            app.FixedLoadPercentageSpinner = uispinner(app.SummaryTab);
            app.FixedLoadPercentageSpinner.Step = 0.05;
            app.FixedLoadPercentageSpinner.Limits = [0.5 1.5];
            app.FixedLoadPercentageSpinner.ValueDisplayFormat = '%5.2f';
            app.FixedLoadPercentageSpinner.ValueChangedFcn = createCallbackFcn(app, @FixedLoadPercentageSpinnerValueChanged, true);
            app.FixedLoadPercentageSpinner.Value = 1;
            app.ExportButton = uibutton(app.SummaryTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FileNameEditFieldLabel = uilabel(app.SummaryTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SummaryTab, 'text');
            app.FileNameEditField.Value = 'case39mod';
            app.ImportButton = uibutton(app.SummaryTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.q1Button = uibutton(app.SummaryTab, 'push');
            app.q1Button.ButtonPushedFcn = createCallbackFcn(app, @q1ButtonPushed2, true);
            app.q1Button.Text = '?';
            app.q2Button = uibutton(app.SummaryTab, 'push');
            app.q2Button.ButtonPushedFcn = createCallbackFcn(app, @q2ButtonPushed2, true);
            app.q2Button.Text = '?';
            app.q3Button = uibutton(app.SummaryTab, 'push');
            app.q3Button.ButtonPushedFcn = createCallbackFcn(app, @q3ButtonPushed2, true);
            app.q3Button.Text = '?';
            app.UserMangementTab = uitab(app.TabGroup3);
            app.UserMangementTab.Title = 'User Mangement';
            app.UITable9 = uitable(app.UserMangementTab);
            app.UITable9.ColumnName = {'User ID'; 'Username'; 'Password'};
            app.UITable9.RowName = {};
            app.NewPasswordEditFieldLabel = uilabel(app.UserMangementTab);
            app.NewPasswordEditFieldLabel.Text = 'New Password :';
            app.NewPasswordEditField = uieditfield(app.UserMangementTab, 'text');
            app.ApplyChangesButton = uibutton(app.UserMangementTab, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.UserIDLabel = uilabel(app.UserMangementTab);
            app.UserIDLabel.Text = 'User ID :';
            app.UserIDSpinner = uispinner(app.UserMangementTab);
            app.UserIDSpinner.Limits = [1 15];
            app.UserIDSpinner.Value = 1;
            app.NewUsernameEditFieldLabel = uilabel(app.UserMangementTab);
            app.NewUsernameEditFieldLabel.Text = 'New Username :';
            app.NewUsernameEditField = uieditfield(app.UserMangementTab, 'text');
            app.ApplyChangesButton1 = uibutton(app.UserMangementTab, 'push');
            app.ApplyChangesButton1.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButton1Pushed, true);
            app.ApplyChangesButton1.Text = 'Apply Changes';
            app.UITable10 = uitable(app.UserMangementTab);
            app.UITable10.ColumnName = {'From'; 'To'; 'Text'; 'Date'};
            app.UITable10.RowName = {};
            app.UITable10.ColumnSortable = [true true false true];
            app.RefreshMailsButton = uibutton(app.UserMangementTab, 'push');
            app.RefreshMailsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshMailsButtonPushed, true);
            app.RefreshMailsButton.Text = 'Refresh Mails';
            app.UITable11 = uitable(app.UserMangementTab);
            app.UITable11.ColumnName = {'User'; 'Action'; 'Date'};
            app.UITable11.RowName = {};
            app.UITable11.ColumnSortable = [true false true];
            app.RefreshLogsButton = uibutton(app.UserMangementTab, 'push');
            app.RefreshLogsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshLogsButtonPushed, true);
            app.RefreshLogsButton.Text = 'Refresh Logs';
            app.GridInfoTab = uitab(app.TabGroup);
            app.GridInfoTab.Title = 'Grid Info';
            app.UIAxes3 = uiaxes(app.GridInfoTab);
            title(app.UIAxes3, {'Map'; ''})
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UITable5 = uitable(app.GridInfoTab);
            app.UITable5.ColumnName = {'Bus'; 'Pg'; 'Price'; 'Price Diff(%)'; 'profit'};
            app.UITable5.RowName = {};
            app.UpdateButton_4 = uibutton(app.GridInfoTab, 'push');
            app.UpdateButton_4.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_4Pushed, true);
            app.UpdateButton_4.Text = {'Update'; ''};
            app.RoundSpinner_3Label = uilabel(app.GridInfoTab);
            app.RoundSpinner_3Label.Text = 'Round';
            app.RoundSpinner_3 = uispinner(app.GridInfoTab);
            app.RoundSpinner_3.Limits = [0 Inf];
            app.RoundSpinner_3.ValueDisplayFormat = '%.0f';
            app.UITable4_2 = uitable(app.GridInfoTab);
            app.UITable4_2.ColumnName = {'Round'; 'Success'; 'ObjFuncValue'};
            app.UITable4_2.RowName = {};
            app.ExportDataButton = uibutton(app.GridInfoTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.FileNameEditField_2Label = uilabel(app.GridInfoTab);
            app.FileNameEditField_2Label.Text = 'File Name :';
            app.FileNameEditField_2 = uieditfield(app.GridInfoTab, 'text');
            app.FileNameEditField_2.Placeholder = 'e.g. case39data';
            app.LoadManagementTab = uitab(app.TabGroup);
            app.LoadManagementTab.Title = 'Load-Management';
            app.UsersLoginPanel_2 = uipanel(app.LoadManagementTab);
            app.UsersLoginPanel_2.Title = 'UsersLogin';
            app.LoadsLoginPanel = uipanel(app.UsersLoginPanel_2);
            app.LoadsLoginPanel.Title = 'Load Management Login Panel';
            app.SignInButton_3 = uibutton(app.LoadsLoginPanel, 'push');
            app.SignInButton_3.ButtonPushedFcn = createCallbackFcn(app, @SignInButton_3Pushed, true);
            app.SignInButton_3.Text = {'Sign-In'; ''};
            app.UsernameEditField_4Label = uilabel(app.LoadsLoginPanel);
            app.UsernameEditField_4Label.Text = {'Username'; ''};
            app.UsernameEditField_3 = uieditfield(app.LoadsLoginPanel, 'text');
            app.PasswordEditField_4Label = uilabel(app.LoadsLoginPanel);
            app.PasswordEditField_4Label.Text = 'Password';
            app.PasswordEditField_3 = uieditfield(app.LoadsLoginPanel, 'text');
            app.UsersPanel_2 = uipanel(app.LoadManagementTab);
            app.UsersPanel_2.Title = 'UsersPanel';
            app.UsersPanel_2.Visible = 'off';
            app.UIAxes5 = uiaxes(app.UsersPanel_2);
            title(app.UIAxes5, 'Hourly Load')
            xlabel(app.UIAxes5, 'Hour')
            ylabel(app.UIAxes5, 'Load')
            zlabel(app.UIAxes5, 'Z')
            app.LogoutButton_2 = uibutton(app.UsersPanel_2, 'push');
            app.LogoutButton_2.ButtonPushedFcn = createCallbackFcn(app, @LogoutButton_2Pushed, true);
            app.LogoutButton_2.Text = {'Logout'; ''};
            app.LoadChangeLabel = uilabel(app.UsersPanel_2);
            app.LoadChangeLabel.Text = {'Load Change (%)'; ''};
            app.LoadChangeEditField = uieditfield(app.UsersPanel_2, 'numeric');
            app.LoadChangeEditField.Limits = [-20 20];
            app.HourSpinnerLabel = uilabel(app.UsersPanel_2);
            app.HourSpinnerLabel.Text = 'Hour';
            app.HourSpinner = uispinner(app.UsersPanel_2);
            app.HourSpinner.Limits = [1 24];
            app.HourSpinner.Value = 1;
            app.RegisterButton = uibutton(app.UsersPanel_2, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.PlotButton_2 = uibutton(app.UsersPanel_2, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.UITable8 = uitable(app.UsersPanel_2);
            app.UITable8.ColumnName = {'Hour'; 'Load Pct'};
            app.UITable8.RowName = {};
            app.UploadDataButton = uibutton(app.UsersPanel_2, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Text = 'Upload Data';
            app.q4Button = uibutton(app.UsersPanel_2, 'push');
            app.q4Button.ButtonPushedFcn = createCallbackFcn(app, @q4ButtonPushed, true);
            app.q4Button.Text = '?';
            app.UserLabel_2 = uilabel(app.UsersPanel_2);
            app.UserLabel_2.Text = 'User :';
            app.UserEditField_2 = uieditfield(app.UsersPanel_2, 'numeric');
            app.UserEditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.EnergyMarketApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnergyMarketApplicationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnergyMarketApplicationUIFigure)
        end
    end
end